classdef solveBarOneDim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OneDimBarSolverUIFigure        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        NumberofCellsEditField         matlab.ui.control.NumericEditField
        NumberofCellsLabel             matlab.ui.control.Label
        LeftBoundaryTemperatureKEditField  matlab.ui.control.NumericEditField
        LeftBoundaryTemperatureKEditFieldLabel  matlab.ui.control.Label
        RightBoundaryTemperatureKEditField  matlab.ui.control.NumericEditField
        RightBoundaryTemperatureKEditFieldLabel  matlab.ui.control.Label
        VolumetricHeatSourceWm3EditField  matlab.ui.control.NumericEditField
        VolumetricHeatSourceWm3EditFieldLabel  matlab.ui.control.Label
        ThermalConductivityWmKEditField  matlab.ui.control.NumericEditField
        ThermalConductivityWmKEditFieldLabel  matlab.ui.control.Label
        CrossSectionalAream2EditField  matlab.ui.control.NumericEditField
        CrossSectionalAream2EditFieldLabel  matlab.ui.control.Label
        LengthmEditField               matlab.ui.control.NumericEditField
        LengthmEditFieldLabel          matlab.ui.control.Label
        SimulateButton                 matlab.ui.control.Button
        PhysicalParametersLabel        matlab.ui.control.Label
        GridSpecificationsLabel        matlab.ui.control.Label
        BoundaryConditionsLabel        matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        PrincipalInterestUIAxes        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OneDimBarSolverUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {519, 519};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function ButtonPushed(app, event)
                
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)

                T_A = app.LeftBoundaryTemperatureKEditField.Value;
                T_B = app.RightBoundaryTemperatureKEditField.Value;
                q_gen = app.VolumetricHeatSourceWm3EditField.Value;

                A = app.CrossSectionalAream2EditField.Value;
                L = app.LengthmEditField.Value;
                N = app.NumberofCellsEditField.Value;

                k = app.ThermalConductivityWmKEditField.Value;
                L_cell = L / N;         %length of each cell in [m]
                d_LP = L_cell;          %distance bt left and point cell in consideration of centroids in [m]
                d_PR = L_cell;          %distance bt point and right cell in consideration of centroids in [m]
                d = d_LP;

               
                x_CFD = L_cell/2:L_cell:L;
                x_CFD = [0; x_CFD.'; L];

                % Calculate material properties
                DA = k * A / d;         %material parameter in [W/K]
                Dl_Al = DA;
                Dr_Ar = DA;
                SV = q_gen * A * L_cell;%heat source per unit volume per cell in [W]

                A_mat = zeros(N,N);
                S_mat = zeros(N,1);

                % Assemble matrix
                for i = 1:N
                    for j = 1:N
                        if i == 1
                            %Left boundary cell
                            a_L = 0;
                            a_R = Dr_Ar;

                            S_p = -2 * Dl_Al;
                            S_u = (T_A - 273) * (2 * Dl_Al) + q_gen * A * L_cell;

                            a_p = a_L + a_R - S_p;

                            A_mat(i, 1) = a_p;
                            A_mat(i, 2) = -a_R;
                            if j > 2
                                A_mat(i, j) = 0;
                            end
                            S_mat(i, 1) = S_u;
                        elseif i == N
                            %Right boundary cell
                            a_L = Dl_Al;
                            a_R = 0;

                            S_p = -2 * Dr_Ar;
                            S_u = (T_B - 273)*(2 * Dr_Ar) + q_gen * A * L_cell;

                            a_p = a_L + a_R - S_p;

                            A_mat(i, N-1) = -a_L;
                            A_mat(i, N) = a_p;
                            if j < N - 1
                                A_mat(i, j) = 0;
                            end
                            S_mat(i, 1) = S_u;
                        else
                            %Interior cells
                            a_L = Dl_Al;
                            a_R = Dr_Ar;

                            S_p = 0;
                            S_u = q_gen * A * L_cell;

                            a_p = a_L + a_R - S_p;

                            A_mat(i, i-1) = -a_L;
                            A_mat(i, i) = a_p;
                            A_mat(i, i+1) = -a_R;
                            if j > (i + 1)
                                A_mat(i, j) = 0;
                            end

                            S_mat(i, 1) = S_u;
                        end
                    end
                end

                %Solve system
                
                T_CFD = pcg(A_mat, S_mat, 1e-6, 1000); %preconditioned conjugate gradients method
                T_CFD = [T_A-273; T_CFD; T_B-273];

                x = linspace(0, L, 5000);

                %Calcualte analytical temperature distribution
                T_Analytical = T_A + x ./ L .* (T_B - T_A) + q_gen ./ (2 * k) .* x .* (L - x) - 273;

                plot(app.PrincipalInterestUIAxes, x_CFD, T_CFD, 'o'); hold(app.PrincipalInterestUIAxes, 'on');
                plot(app.PrincipalInterestUIAxes, x, T_Analytical, '-b', 'LineWidth', 2);
                hold(app.PrincipalInterestUIAxes,'off');
                legend(app.PrincipalInterestUIAxes,{'CFD','Analytical'},'Location','Best')
                xlim(app.PrincipalInterestUIAxes,'auto'); 
                ylim(app.PrincipalInterestUIAxes,'auto');
  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OneDimBarSolverUIFigure = uifigure('Visible', 'off');
            app.OneDimBarSolverUIFigure.Name = '1D Conduction Simulation';
            app.OneDimBarSolverUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.OneDimBarSolverUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BoundaryConditionsLabel = uilabel(app.LeftPanel);
            app.BoundaryConditionsLabel.Text = 'Boundary Conditions';
            app.GridSpecificationsLabel = uilabel(app.LeftPanel);
            app.GridSpecificationsLabel.Text = 'Grid Specifications';
            app.PhysicalParametersLabel = uilabel(app.LeftPanel);
            app.PhysicalParametersLabel.Text = 'Physical Parameters';
            app.SimulateButton = uibutton(app.LeftPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.LengthmEditFieldLabel = uilabel(app.LeftPanel);
            app.LengthmEditFieldLabel.Text = 'Length [m]';
            app.LengthmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CrossSectionalAream2EditFieldLabel = uilabel(app.LeftPanel);
            app.CrossSectionalAream2EditFieldLabel.Text = {'Cross-Sectional'; 'Area [m^2]'};
            app.CrossSectionalAream2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.ThermalConductivityWmKEditFieldLabel = uilabel(app.LeftPanel);
            app.ThermalConductivityWmKEditFieldLabel.Text = {'Thermal Conductivity'; '[W/(m-K)]'};
            app.ThermalConductivityWmKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VolumetricHeatSourceWm3EditFieldLabel = uilabel(app.LeftPanel);
            app.VolumetricHeatSourceWm3EditFieldLabel.Text = {'Volumetric Heat'; 'Source [W/m^3]'};
            app.VolumetricHeatSourceWm3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightBoundaryTemperatureKEditFieldLabel = uilabel(app.LeftPanel);
            app.RightBoundaryTemperatureKEditFieldLabel.Text = {'Right Boundary'; 'Temperature [K]'};
            app.RightBoundaryTemperatureKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LeftBoundaryTemperatureKEditFieldLabel = uilabel(app.LeftPanel);
            app.LeftBoundaryTemperatureKEditFieldLabel.Text = {'Left Boundary'; 'Temperature [K]'};
            app.LeftBoundaryTemperatureKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofCellsLabel = uilabel(app.LeftPanel);
            app.NumberofCellsLabel.Text = 'Number of Cells';
            app.NumberofCellsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'Temperature Distribution')
            xlabel(app.PrincipalInterestUIAxes, 'x [m]')
            ylabel(app.PrincipalInterestUIAxes, 'Temperature [°C]')
            % Show the figure after all components are created
            app.OneDimBarSolverUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = solveBarOneDim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OneDimBarSolverUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OneDimBarSolverUIFigure)
        end
    end
end